classdef game1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        InstructionsPanel       matlab.ui.container.Panel
        HowtoPlayLabel          matlab.ui.control.Label
        TextArea                matlab.ui.control.TextArea
        BacktoHomeButton        matlab.ui.control.Button
        PlayPanel               matlab.ui.container.Panel
        QuitButton              matlab.ui.control.Button
        WordCountdownLabel      matlab.ui.control.Label
        SubmitWordButton        matlab.ui.control.Button
        EditField5              matlab.ui.control.EditField
        EditField4              matlab.ui.control.EditField
        EditField3              matlab.ui.control.EditField
        EditField2              matlab.ui.control.EditField
        EditField               matlab.ui.control.EditField
        Button5                 matlab.ui.control.Button
        Button4                 matlab.ui.control.Button
        Button3                 matlab.ui.control.Button
        Button2                 matlab.ui.control.Button
        Button                  matlab.ui.control.Button
        GameModePanel           matlab.ui.container.Panel
        BeginButton             matlab.ui.control.Button
        HowtoPlayButton         matlab.ui.control.Button
        HomeLabel               matlab.ui.control.Label
        WelcomePanel            matlab.ui.container.Panel
        CLPS0950March2025Label  matlab.ui.control.Label
        ByKiaraIbanezDiazandOliviaWangLabel  matlab.ui.control.Label
        Button_2                matlab.ui.control.Button
        WELCOMETOLabel          matlab.ui.control.Label
    end

   %edited by olivia March 21, 2025 9:25pm-11:02
    %objectives:
        %editing/cleaning up some comments
        %debugging listtextbox & label spacing
        %cleaning up alerts/buttons/fonts/overall look and design
        %attempting for about an hour to fix robust positioning and reposition of tiles and slots
        %but ultimately did NOT fix the robust sizing issues of the tiles and slots 
        %that is a known issue however
        %added quit button/a button that will show the user the words they
        %missed/and will let them exit prematurely if they want
      

    %kiara
  properties (Access = private)
        DraggingTile            % Currently dragging tile
        Dictionary              % Dictionary of valid words
        CurrentLetters          % Current letters for the game
        PossibleWords           % Possible valid words from CurrentLetters
        FoundWords = {}         % Words already used
        RemainingWordsCount     % Counter for remaining words
        WordSlots               % Array of word slots
        LetterTiles             % Array of letter tiles
        MaxTiles = 7            % Max number of letter tiles to display
    end
    
    methods (Access = private)
        function startupFcn(app)
            % Load dictionary
            app.loadDictionary();
            % Show welcome panel
            showPanel(app, app.WelcomePanel);
        end


        function showPanel(app, panel)
            % Hide all panels
            app.WelcomePanel.Visible = 'off';
            app.GameModePanel.Visible = 'off';
            app.PlayPanel.Visible = 'off';
            app.InstructionsPanel.Visible = 'off';

            % Show only selected panel
            panel.Visible = 'on';
        end

        function loadDictionary(app)
            % Checks if dictionary file is working
            try % Accounts for potential errors
                fid = fopen('dictionary7.txt');
                if fid == -1 %Checks if file opened
                    % If file doesn't exist, create simple dictionary
                    app.Dictionary = {'CAT', 'ACT', 'DOG', 'GOD', 'DEAR', 'READ', 'DARE', 'BEAR', 'BARE', 'TABLE', 'BLADE', 'SILENT', 'LISTEN'};
                    warning('dictionary7.txt not found. Using built-in dictionary.');
                    return;
                end
                
                wordData = textscan(fid, '%s'); % Reads file (strings) with textscan
                app.Dictionary = upper(wordData{1}); % Converts to upper case uniformly
                fclose(fid);
                disp(['Loaded ' num2str(length(app.Dictionary)) ' words from dictionary']);
            catch ex %Catches potential errors
                warning(['Error loading dictionary: ' ex.message]);
                % Use backup dictionary
                app.Dictionary = {'CAT', 'ACT', 'DOG', 'GOD', 'DEAR', 'READ', 'DARE', 'BEAR', 'BARE', 'TABLE', 'BLADE', 'SILENT', 'LISTEN'};
            end
        end

        function generateGame(app)
           disp('Generating game with random letters');

           % Loads dictionary
            if isempty(app.Dictionary)
                app.loadDictionary();
            end

            % Clear existing letter tiles
            app.clearLetterTiles();
    
            % Find words with 3-7 characters (7 is the max)
            potentialWords = app.Dictionary(cellfun(@length, app.Dictionary) >= 3 & cellfun(@length, app.Dictionary) <= app.MaxTiles);
    
            % Checks if dictionary words meet the criteria
            if isempty(potentialWords)
                error('No suitable words found in the dictionary');
            end

            bestLetters = '';
            bestWordCount = 0;
            maxAttempts = 50;

            %Find the best set of letters
            for attempt = 1:maxAttempts
                % Selects random word from potential words, makes uppercase
                randomIndex = randi(length(potentialWords));
                selectedWord = upper(potentialWords{randomIndex});
        
                if length(selectedWord) > app.MaxTiles
                    continue; % Skip words that are too long
                end

                % Looks for all valid words that can be made from selected
                % letters
                possibleWords = app.findAllPossibleWords(selectedWord);
        
                if length(possibleWords) > bestWordCount && length(possibleWords) <= 10
                    bestLetters = selectedWord; 
                    bestWordCount = length(possibleWords); %Updates to new best word
            
                    if bestWordCount >= 3 % Exit loop if at least 3 words possible
                        break;
                    end
                end
            end

            %If no valid letters found, go back to previous selected word
            if bestWordCount < 1  
                bestLetters = selectedWord; 
            end

            % Save letters and words chosen, sets possible words left
            app.CurrentLetters = bestLetters;
            app.PossibleWords = app.findAllPossibleWords(bestLetters);
            app.FoundWords = {};
            app.RemainingWordsCount = length(app.PossibleWords);
    
            % Update label for countdown
            app.WordCountdownLabel.Text = ['Words left: ' num2str(app.RemainingWordsCount)];
    
            % Access found words through UserData 
            if isfield(app.UIFigure.UserData, 'FoundWordsList')
                app.UIFigure.UserData.FoundWordsList.Items = {};
            end

            % Scramble the letter tiles
            scrambledIndices = randperm(length(bestLetters));
            scrambledLetters = bestLetters(scrambledIndices);
    
            % Create letter tiles
            app.createLetterTiles(scrambledLetters);
    
            % Create word slots for the word's length
            app.createWordSlots(length(bestLetters));
    
            % For debugging and command window display
            disp(['Game generated with letters: ' bestLetters]);
            disp(['Possible words: ' num2str(length(app.PossibleWords))]);
            for i = 1:length(app.PossibleWords)
                disp([' ' app.PossibleWords{i}]);
            end
        end


        function possibleWords = findAllPossibleWords(app, letters)
            % Initialize possible words
            possibleWords = {};
            % Make upper case
            letters = upper(letters);
            
            % Check each word in the dictionary file
            for i = 1:length(app.Dictionary)
                word = upper(app.Dictionary{i});
                
                % Skip words that are too short or long for the amount of letters
                if length(word) ~= length(letters)
                    continue;
                end
                % Check if word can be formed with selected letters
                if app.canFormWord(word, letters)
                    possibleWords{end+1} = word; %If possible, add to possible words count
                end
            end
        end


        %checks how many words can be formed from the letters given
        function result = canFormWord(app, word, letters)
            word = upper(word);
            letters = upper(letters);
            
            % Sort to compare sets of characters
            wordChars = sort(word);
            lettersChars = sort(letters);
            %Compare
            result = strcmp(wordChars, lettersChars);
        end
        
        function clearLetterTiles(app)
            % Delete any existing letter tiles
            if ~isempty(app.LetterTiles)
                for i = 1:length(app.LetterTiles)
                    if isvalid(app.LetterTiles(i))
                        delete(app.LetterTiles(i));
                    end
                end
            end
            app.LetterTiles = []; % Clear tile array
        end


        % Make the tiles
        function createLetterTiles(app, letters)
            % Clear any existing tiles
            app.clearLetterTiles();


            %Define consistent dimensions for tiles
            tileWidth = 40;
            tileHeight = 40;
            spacing = 10;
    
            % Calculate starting position to center the tiles
            totalWidth = tileWidth * length(letters) + spacing * (length(letters) - 1); %Total width to display tiles
            startX = (app.PlayPanel.Position(3) - totalWidth) / 2; % Calculate starting x coordinate for tile centering
            startY = 180; %Consistent y coordinate
    
            % Create graphics objects array to store tiles
            app.LetterTiles = gobjects(1, length(letters));
    
    
            % Create, position and style each letter tile for amount of letters in word
            for i = 1:length(letters)
        
                tile = uibutton(app.PlayPanel, 'push');
                tile.Position = [startX + (i-1)*(tileWidth + spacing), startY, tileWidth, tileHeight];
                tile.Text = letters(i);
                tile.BackgroundColor = [0.8, 0.8, 1];
                tile.FontSize = 16;
                tile.FontName = 'Georgia';
                tile.FontWeight = 'bold';
                tile.Tag = 'LetterTile';
        
                % Store data and original position for the tiles, track tile position
                tile.UserData = struct('originalPosition', tile.Position, 'slotIndex', []);
        
                % Set callback for drag start when the tile is clicked
                tile.ButtonPushedFcn = createCallbackFcn(app, @letterTileClicked, true);
        
                app.LetterTiles(i) = tile;
            end
    
                % Hide the predefined buttons
                if isvalid(app.Button)
                    app.Button.Visible = 'off';
                end
                if isvalid(app.Button2)
                    app.Button2.Visible = 'off';
                end
                if isvalid(app.Button3)
                    app.Button3.Visible = 'off';
                end
                if isvalid(app.Button4)
                    app.Button4.Visible = 'off';
                end
                if isvalid(app.Button5)
                    app.Button5.Visible = 'off';
                end
           end


      % Make Slots  
       function createWordSlots(app, wordLength)
       
   
            % Delete existing word slots
            if ~isempty(app.WordSlots)
                for i = 1:length(app.WordSlots)
                    if isvalid(app.WordSlots(i))
                        delete(app.WordSlots(i));
                    end
                end
            end
    
            % Debug statement
            disp(['Creating ' num2str(wordLength) ' word slots']);
    
            %was here
            slotWidth = 40;
            slotHeight = 40;
            spacing = 10;
    
            % Get the container from UserData
            if isfield(app.UIFigure.UserData, 'EditFieldContainer')
                editFieldContainer = app.UIFigure.UserData.EditFieldContainer;
        
                % Calculate starting position to center the slots
                totalWidth = slotWidth * wordLength + spacing * (wordLength - 1);

                    if totalWidth > editFieldContainer.Position(3)
                        newWidth = totalWidth + 20; % Add some padding
                        currentPos = editFieldContainer.Position;
            
                        % Center the container if we're changing the width
                        newX = app.PlayPanel.Position(3)/2 - newWidth/2;
                        editFieldContainer.Position = [newX, currentPos(2), newWidth, currentPos(4)];
                    end
        
                    % Calculate starting position to center the slots
                    startX = (editFieldContainer.Position(3) - totalWidth) / 2;
                    startY = 10;  % Low position within the container
                    app.WordSlots = gobjects(1, wordLength);
        
                    for i = 1:wordLength
                        slot = uieditfield(editFieldContainer, 'text');
                        slot.Position = [startX + (i-1)*(slotWidth + spacing), startY, slotWidth, slotHeight];
                        slot.Tag = 'WordSlot';
                        slot.BackgroundColor = [0.9, 0.9, 0.9];
                        slot.HorizontalAlignment = 'center';
                        slot.FontSize = 16;
                        slot.Editable = 'off';
                        slot.Value = '';
            
                        app.WordSlots(i) = slot;
                        disp(['Created word slot ' num2str(i)]);
                    end
            else
                % Fallback if container doesn't exist
                disp('Using fallback method for creating word slots');
                app.WordSlots = gobjects(1, wordLength);
                    for i = 1:wordLength
                        slot = uieditfield(app.PlayPanel, 'text');
                        slot.Position = [90 + (i-1)*45, 82, 40, 32];  % Increased spacing
                        slot.Tag = 'WordSlot';
                        slot.BackgroundColor = [0.9, 0.9, 0.9];
                        slot.HorizontalAlignment = 'center';
                        slot.FontSize = 16;
                        slot.Editable = 'off';
                        slot.Value = '';
            
                        app.WordSlots(i) = slot;
                        disp(['Created word slot ' num2str(i)]);
                    end
            end
        end


        function letterTileClicked(app, event)
    % Handle letter tile click to start dragging
    src = event.Source; % Get the source component that triggered the event
    
    if strcmp(src.Tag, 'LetterTile')
        app.startTileDrag(src);
    end
end
        
        function startTileDrag(app, src)

    % Begin dragging a tile
    app.DraggingTile = src;
    
    % If the tile is in a slot, clear the slot
    if isfield(src.UserData, 'slotIndex') && ~isempty(src.UserData.slotIndex)
        slotIndex = src.UserData.slotIndex;
        if slotIndex <= length(app.WordSlots) && isvalid(app.WordSlots(slotIndex))
            app.WordSlots(slotIndex).Value = '';
        end
        src.UserData.slotIndex = [];
    end
    
    % Ensure UserData exists
    if ~isfield(src.UserData, 'originalPosition')
        src.UserData.originalPosition = src.Position;
    end
    
    % Make the tile appear on top
    uistack(src, 'top');
    
    % Change appearance to show it's being dragged
    src.FontWeight = 'bold';
    src.BackgroundColor = [0.9, 0.7, 0.7];
    
    % Set up drag and drop event handlers
    set(app.UIFigure, 'WindowButtonMotionFcn', @(~,~)app.dragTile());
    set(app.UIFigure, 'WindowButtonUpFcn', @(~,~)app.dropTile());
    
    disp(['Started dragging tile: ' src.Text]);
end
        
        function dragTile(app)
            % Move the tile with the mouse cursor
            if isempty(app.DraggingTile) || ~isvalid(app.DraggingTile)
                return;
            end
            
            % Get current mouse position relative to the figure
            currentPoint = app.UIFigure.CurrentPoint;
            
            % Keep the tile size the same
            tileWidth = app.DraggingTile.Position(3);
            tileHeight = app.DraggingTile.Position(4);
            
            % Center the tile on the mouse cursor
            newX = currentPoint(1) - (tileWidth / 2);
            newY = currentPoint(2) - (tileHeight / 2);
            
            % Make sure the tile stays within the figure bounds
            figWidth = app.UIFigure.Position(3);
            figHeight = app.UIFigure.Position(4);
            
            newX = max(0, min(newX, figWidth - tileWidth));
            newY = max(0, min(newY, figHeight - tileHeight));
            
            % Update the tile position
            app.DraggingTile.Position = [newX, newY, tileWidth, tileHeight];
            
            drawnow update;
        end
        
       function dropTile(app)
    % Handle dropping the tile
    
    % Validate that we have a valid dragging tile
    if isempty(app.DraggingTile) || ~isobject(app.DraggingTile) || ~isvalid(app.DraggingTile)
        % Reset event handlers
        set(app.UIFigure, 'WindowButtonMotionFcn', '');
        set(app.UIFigure, 'WindowButtonUpFcn', '');
        return;
    end
    
    try
        % Reset appearance
        app.DraggingTile.FontWeight = 'normal';
        app.DraggingTile.BackgroundColor = [0.8, 0.8, 1];
    catch
        % If an error occurs, just clean up and return
        app.DraggingTile = [];
        set(app.UIFigure, 'WindowButtonMotionFcn', '');
        set(app.UIFigure, 'WindowButtonUpFcn', '');
        return;
    end
    
    % Reset event handlers
    set(app.UIFigure, 'WindowButtonMotionFcn', '');
    set(app.UIFigure, 'WindowButtonUpFcn', '');
    
    % Check if we have valid word slots
    if isempty(app.WordSlots)
        disp('No word slots available');
        if isfield(app.DraggingTile.UserData, 'originalPosition')
            app.DraggingTile.Position = app.DraggingTile.UserData.originalPosition;
        end
        app.DraggingTile = [];
        return;
    end
    
    % Check if the tile is over any word slot
    placed = false;
    
    for i = 1:length(app.WordSlots)
        if ~isvalid(app.WordSlots(i))
            continue;
        end
        
        if app.isOverlapping(app.DraggingTile, app.WordSlots(i))
            % Check if the slot is already occupied
            if ~isempty(app.WordSlots(i).Value)
                disp(['Slot ' num2str(i) ' is already occupied']);
                continue;
            end
            
            try
                % Get the container from UserData
                if isfield(app.UIFigure.UserData, 'EditFieldContainer') && ...
                   isobject(app.UIFigure.UserData.EditFieldContainer) && ...
                   isvalid(app.UIFigure.UserData.EditFieldContainer)
                    containerPos = app.UIFigure.UserData.EditFieldContainer.Position;
                    slotPos = app.WordSlots(i).Position;
                    
                    % Position the tile over the slot
                    newX = containerPos(1) + slotPos(1) + (slotPos(3) - app.DraggingTile.Position(3)) / 2;
                    newY = containerPos(2) + slotPos(2) + (slotPos(4) - app.DraggingTile.Position(4)) / 2;
                    
                    app.DraggingTile.Position = [newX, newY, app.DraggingTile.Position(3), app.DraggingTile.Position(4)];
                else
                    % Fallback if container doesn't exist
                    slotPos = app.WordSlots(i).Position;
                    app.DraggingTile.Position = [slotPos(1), slotPos(2), app.DraggingTile.Position(3), app.DraggingTile.Position(4)];
                end
                
                uistack(app.DraggingTile, 'top');
                
                % If this tile was in another slot, clear that slot
                if isfield(app.DraggingTile.UserData, 'slotIndex') && ~isempty(app.DraggingTile.UserData.slotIndex)
                    prevSlotIndex = app.DraggingTile.UserData.slotIndex;
                    if prevSlotIndex <= length(app.WordSlots) && isvalid(app.WordSlots(prevSlotIndex))
                        app.WordSlots(prevSlotIndex).Value = '';
                    end
                end
                
                % Store which slot this tile is in
                app.DraggingTile.UserData.slotIndex = i;
                
                % Set the value in the slot
                app.WordSlots(i).Value = app.DraggingTile.Text;
                
                placed = true;
                disp(['Placed in slot ' num2str(i)]);
                break;
            catch err
                disp(['Error placing tile: ' err.message]);
                placed = false;
            end
        end
    end
    
    if ~placed
        % Return to original position
        try
            if isfield(app.DraggingTile.UserData, 'originalPosition')
                app.DraggingTile.Position = app.DraggingTile.UserData.originalPosition;
            end
        catch
            % Ignore error if we can't set position
        end
        disp('Tile not placed in any valid slot');
    end
    
    % Check if all slots are filled, to enable submit button
    allFilled = true;
    for i = 1:length(app.WordSlots)
        if ~isvalid(app.WordSlots(i)) || isempty(app.WordSlots(i).Value)
            allFilled = false;
            break;
        end
    end
    
    try
        app.SubmitWordButton.Enable = allFilled;
    catch
        % Ignore error if we can't set button state
    end
    
    app.DraggingTile = [];
    drawnow;
end
        
        function overlap = isOverlapping(app, tile, slot)
    % Check if a tile overlaps with a slot
    
    % Validate that the tile and slot are valid objects
    if isempty(tile) || ~isobject(tile) || ~isvalid(tile) || ~isprop(tile, 'Position')
        overlap = false;
        return;
    end
    
    if isempty(slot) || ~isobject(slot) || ~isvalid(slot) || ~isprop(slot, 'Position')
        overlap = false;
        return;
    end
    
    % Get container position from UserData
    if isfield(app.UIFigure.UserData, 'EditFieldContainer') && ...
       isobject(app.UIFigure.UserData.EditFieldContainer) && ...
       isvalid(app.UIFigure.UserData.EditFieldContainer)
        containerPos = app.UIFigure.UserData.EditFieldContainer.Position;
    else
        containerPos = [0, 0, 0, 0];  % Default if container doesn't exist
    end
    
    % Get positions safely
    try
        tilePos = tile.Position;
        slotPos = slot.Position;
    catch
        % If there's an error accessing positions, return false
        overlap = false;
        return;
    end
    
    % Calculate absolute slot position
    absoluteSlotPos = [containerPos(1) + slotPos(1), containerPos(2) + slotPos(2), slotPos(3), slotPos(4)];
    
    % Calculate centers of tile and slot
    tileCenter = [tilePos(1) + tilePos(3)/2, tilePos(2) + tilePos(4)/2];
    slotCenter = [absoluteSlotPos(1) + absoluteSlotPos(3)/2, absoluteSlotPos(2) + absoluteSlotPos(4)/2];
    
    % Calculate distance between centers
    distance = sqrt((tileCenter(1) - slotCenter(1))^2 + (tileCenter(2) - slotCenter(2))^2);
    
    % Check if they're close enough to consider overlapping
    overlap = (distance < 40);
end
        
        function resetTiles(app)
            % Reset all tiles to their original positions
            
            % Clear all slots
            for i = 1:length(app.WordSlots)
                app.WordSlots(i).Value = '';
            end
            
            % Return letter tiles to original positions
            for i = 1:length(app.LetterTiles)
                if ~isvalid(app.LetterTiles(i))
                    continue;
                end
                
                % Clear slot association
                if isfield(app.LetterTiles(i).UserData, 'slotIndex')
                    app.LetterTiles(i).UserData.slotIndex = [];
                end
                
                % Return to original position
                if isfield(app.LetterTiles(i).UserData, 'originalPosition')
                    app.LetterTiles(i).Position = app.LetterTiles(i).UserData.originalPosition;
                end
                
                app.LetterTiles(i).FontWeight = 'normal';
                app.LetterTiles(i).BackgroundColor = [0.8, 0.8, 1];
                
                uistack(app.LetterTiles(i), 'top');
            end
            
            app.SubmitWordButton.Enable = false;
            
            drawnow;
        end
        
        function checkWord(app)
            % Check if the word in the slots is valid
    
    % Collect the letters from the slots
    formedWord = '';
    for i = 1:length(app.WordSlots)
        formedWord = [formedWord, app.WordSlots(i).Value];
    end
    
    % Ensure all slots are filled
    if length(formedWord) ~= length(app.WordSlots)
        uialert(app.UIFigure, 'Please use all available slots.', 'Incomplete Word');
        return;
    end
    
    % Convert to uppercase for consistent comparison
    formedWord = upper(formedWord);
    
    disp(['Checking word: ' formedWord]);
    
    % Check if the word is valid and not already found
    isValid = ismember(formedWord, app.PossibleWords);
    alreadyFound = ismember(formedWord, app.FoundWords);
    
    if isValid && ~alreadyFound
        app.FoundWords{end+1} = formedWord;
        app.RemainingWordsCount = app.RemainingWordsCount - 1;
        
        % Update FoundWordsList through UserData
        if isfield(app.UIFigure.UserData, 'FoundWordsList')
            app.UIFigure.UserData.FoundWordsList.Items = app.FoundWords;
        end
        
        app.WordCountdownLabel.Text = ['Words left: ' num2str(app.RemainingWordsCount)];
        
        %added more accurate popups -olivia
        if app.RemainingWordsCount > 0
            uialert(app.UIFigure, ['Correct word! ' num2str(app.RemainingWordsCount) ' left to go'], 'Success', 'Icon', 'info');
        else
            uialert(app.UIFigure, 'Congratulations! You found all the words! Press Begin to Play Again', 'Game Complete', 'Icon', 'success');


            %clear the found words list before hiding the panel - olivia
            %debugging help cuz this gets wonky
            app.FoundWords = {};
            if isfield(app.UIFigure.UserData, 'FoundWordsList')
                app.UIFigure.UserData.FoundWordsList.Items = {};
            end 

            app.PlayPanel.Visible = 'off';
            app.showPanel(app.GameModePanel);
            return;
        end
        
        app.resetTiles();
    elseif alreadyFound
        uialert(app.UIFigure, 'You already found this word!', 'Duplicate Word');
        app.resetTiles();
    else
        uialert(app.UIFigure, 'Not a valid word. Try again!', 'Invalid Word');
        app.resetTiles();
    end
        end
        
        function showPlayScreen(app)
    % Prepare the play screen
    app.showPanel(app.PlayPanel);

    if isvalid(app.EditField)
        app.EditField.Visible = 'off';
    end
    if isvalid(app.EditField2)
        app.EditField2.Visible = 'off';
    end
    if isvalid(app.EditField3)
        app.EditField3.Visible = 'off';
    end
    if isvalid(app.EditField4)
        app.EditField4.Visible = 'off';
    end
    if isvalid(app.EditField5)
        app.EditField5.Visible = 'off';
    end
    
    % Create container for word slots
    editFieldContainer = uipanel(app.PlayPanel);
    editFieldContainer.BorderType = 'none';
    editFieldContainer.BackgroundColor = app.PlayPanel.BackgroundColor;
    editFieldContainer.Position = [35, 100, 300, 50];
    

    %added to make positioning more robust - olivia
    % Get panel dimensions
    panelWidth = app.PlayPanel.Position(3);
    panelHeight = app.PlayPanel.Position(4);

    % Define relative positions and sizes
    listBoxWidth = max(min(120, panelWidth * 0.15), 80); % 15% of panel width, between 80-100px max
    listBoxHeight = max(min(50, panelHeight * 0.15), 40); % 15% of panel, between 40-60px max
    labelWidth = listBoxWidth;
    labelHeight = 22;
    margin = min(16, panelWidth * 0.03); % 3% margin, 16px max

    % Position relative to panel height
    listBoxY = min(panelHeight * 0.7, panelHeight - listBoxHeight - labelHeight -10); % 70% from top
    labelY = listBoxY + listBoxHeight; % Position label above listbox

    % Create FoundWordsList
    foundWordsList = uilistbox(app.PlayPanel);
    foundWordsList.Position = [margin, listBoxY, listBoxWidth, listBoxHeight];
    foundWordsList.Items = {};
    foundWordsList.Visible = 'on';
    
    % Create FoundWordsLabel
    foundWordsLabel = uilabel(app.PlayPanel);
    foundWordsLabel.Text = 'Found Words:';
    foundWordsLabel.Position = [margin, labelY, labelWidth, labelHeight];
    foundWordsLabel.Visible = 'on';
    foundWordsLabel.FontSize = 12;
    foundWordsLabel.FontName = 'Georgia';
    
    % Store these UI elements in the app's UserData
    app.UIFigure.UserData.EditFieldContainer = editFieldContainer;
    app.UIFigure.UserData.FoundWordsList = foundWordsList;
    app.UIFigure.UserData.FoundWordsLabel = foundWordsLabel;
    
    % Generate a new game
    app.generateGame();
    
    % Disable submit button until all slots are filled
    app.SubmitWordButton.Enable = false;
end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.showPanel(app.GameModePanel);
        end

        % Button pushed function: HowtoPlayButton
        function HowtoPlayButtonPushed(app, event)
            app.showPanel(app.InstructionsPanel);

        end

        % Button pushed function: BeginButton
        function BeginButtonPushed(app, event)
            app.showPlayScreen();

        end

        % Button pushed function: SubmitWordButton
        function SubmitWordButtonPushed(app, event)
            app.checkWord();
        end

        % Button pushed function: Button, Button2, Button3, Button4, 
        % ...and 1 other component
        function LetterTilesClicked(app, event)
       clickedButton = event.Source;

    if isfield(clickedButton.UserData, 'slotIndex') && ~isempty(clickedButton.UserData.slotIndex)
        slotIndex = clickedButton.UserData.slotIndex;
    
        % Clear the slot - fixed case sensitivity
        app.WordSlots(slotIndex).Value = '';

        clickedButton.UserData.slotIndex = [];
    
        % Return the button to its original position
        if isfield(clickedButton.UserData, 'originalPosition')
            clickedButton.Position = clickedButton.UserData.originalPosition;
            drawnow;
            return;
        end
    end

    app.startTileDrag(clickedButton);
        end

        % Button pushed function: BacktoHomeButton
        function BacktoHomeButtonPushed(app, event)
            app.showPanel(app.GameModePanel);
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
          % Show confirmation dialog
            choice = uiconfirm(app.UIFigure, ...
                      'Are you sure? You will lose all your progress.', ...
                      'Confirm Quit', ...
                      'Icon', 'warning', ...
                      'Options', {'Yes', 'No'}, ...
                      'DefaultOption', 2);
    
        % Only quit if they select "Yes"
        if strcmp(choice, 'Yes')
                    % Find words the user didn't discover
        missedWords = setdiff(app.PossibleWords, app.FoundWords);
        
        % If there are missed words, show them
        if ~isempty(missedWords)
            % Format the missed words list for display
            missedWordsText = strjoin(missedWords, ', ');
            
            % Show the missed words
            uialert(app.UIFigure, ...
                   ['Words you missed: ' missedWordsText], ...
                   'Game Summary', ...
                   'Icon', 'info');
            
            % Give them time to read it
            pause(2);
        end
        
       
        %Reset found words list - olivia
        %debugging may be redundant but we'll see...
        app.FoundWords = {};
        if isfield(app.UIFigure.UserData, 'FoundWordsList')
            app.UIFigure.UserData.FoundWordsList.Items = {};
            app.UIFigure.UserData.FoundWordsList.Visible = 'off'; % Explicitly hide the listbox
        end

        % Hide the play panel
        app.PlayPanel.Visible = 'off';
        
        % Show the game mode panel
        app.showPanel(app.GameModePanel);
    end
    % If they select "No", do nothing and return to the game
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WelcomePanel = uipanel(app.UIFigure);
            app.WelcomePanel.Title = 'Welcome Panel';
            app.WELCOMETOLabel = uilabel(app.WelcomePanel);
            app.WELCOMETOLabel.Text = 'WELCOME TO';
            app.Button_2 = uibutton(app.WelcomePanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = fullfile(pathToMLAPP, 'anagram.gif');
            app.Button_2.Text = '';
            app.ByKiaraIbanezDiazandOliviaWangLabel = uilabel(app.WelcomePanel);
            app.ByKiaraIbanezDiazandOliviaWangLabel.Text = 'By Kiara Ibanez-Diaz and Olivia Wang';
            app.CLPS0950March2025Label = uilabel(app.WelcomePanel);
            app.CLPS0950March2025Label.Text = 'CLPS 0950 March 2025';
            app.GameModePanel = uipanel(app.UIFigure);
            app.GameModePanel.Title = 'GameModePanel';
            app.GameModePanel.Visible = 'off';
            app.HomeLabel = uilabel(app.GameModePanel);
            app.HomeLabel.Text = 'Home';
            app.HowtoPlayButton = uibutton(app.GameModePanel, 'push');
            app.HowtoPlayButton.ButtonPushedFcn = createCallbackFcn(app, @HowtoPlayButtonPushed, true);
            app.HowtoPlayButton.Text = 'How to Play';
            app.BeginButton = uibutton(app.GameModePanel, 'push');
            app.BeginButton.ButtonPushedFcn = createCallbackFcn(app, @BeginButtonPushed, true);
            app.BeginButton.Text = 'Begin';
            app.PlayPanel = uipanel(app.UIFigure);
            app.PlayPanel.Title = 'PlayPanel';
            app.PlayPanel.Visible = 'off';
            app.Button = uibutton(app.PlayPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @LetterTilesClicked, true);
            app.Button.Tag = 'LetterTiles';
            app.Button2 = uibutton(app.PlayPanel, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @LetterTilesClicked, true);
            app.Button2.Tag = 'LetterTiles';
            app.Button2.Text = 'Button2';
            app.Button3 = uibutton(app.PlayPanel, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @LetterTilesClicked, true);
            app.Button3.Tag = 'LetterTiles';
            app.Button3.Text = 'Button3';
            app.Button4 = uibutton(app.PlayPanel, 'push');
            app.Button4.ButtonPushedFcn = createCallbackFcn(app, @LetterTilesClicked, true);
            app.Button4.Tag = 'LetterTiles';
            app.Button4.Text = 'Button4';
            app.Button5 = uibutton(app.PlayPanel, 'push');
            app.Button5.ButtonPushedFcn = createCallbackFcn(app, @LetterTilesClicked, true);
            app.Button5.Tag = 'LetterTiles';
            app.Button5.Text = 'Button5';
            app.EditField = uieditfield(app.PlayPanel, 'text');
            app.EditField.Tag = 'WordSlots';
            app.EditField2 = uieditfield(app.PlayPanel, 'text');
            app.EditField2.Tag = 'WordSlots';
            app.EditField3 = uieditfield(app.PlayPanel, 'text');
            app.EditField3.Tag = 'WordSlots';
            app.EditField4 = uieditfield(app.PlayPanel, 'text');
            app.EditField4.Tag = 'WordSlots';
            app.EditField5 = uieditfield(app.PlayPanel, 'text');
            app.EditField5.Tag = 'WordSlots';
            app.SubmitWordButton = uibutton(app.PlayPanel, 'push');
            app.SubmitWordButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitWordButtonPushed, true);
            app.SubmitWordButton.Text = 'Submit Word';
            app.WordCountdownLabel = uilabel(app.PlayPanel);
            app.WordCountdownLabel.Text = 'Word Countdown:';
            app.QuitButton = uibutton(app.PlayPanel, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.InstructionsPanel = uipanel(app.UIFigure);
            app.InstructionsPanel.Title = 'InstructionsPanel';
            app.InstructionsPanel.Visible = 'off';
            app.BacktoHomeButton = uibutton(app.InstructionsPanel, 'push');
            app.BacktoHomeButton.ButtonPushedFcn = createCallbackFcn(app, @BacktoHomeButtonPushed, true);
            app.BacktoHomeButton.Text = 'Back to Home';
            app.TextArea = uitextarea(app.InstructionsPanel);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Click and move tiles from a series of scrambled letters. You will be given the count of possible valid words that can be created from rearranging the given letters. You must use all the tiles given. Once you enter a valid word, it will be added to a list on the side of your screen. Once you’ve entered all possible rearrangements of the letters, congratulations! The game will automatically take you back to the home page and you can press "Begin" to play again.'};
            app.HowtoPlayLabel = uilabel(app.InstructionsPanel);
            app.HowtoPlayLabel.Text = 'How to Play';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = game1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end